classdef splinepatratic25 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        xLabel                          matlab.ui.control.Label
        xEditField                      matlab.ui.control.EditField
        fEditFieldLabel                 matlab.ui.control.Label
        fEditField                      matlab.ui.control.EditField
        INTERPOLARESPLINEPATRATICALabel  matlab.ui.control.Label
        FunctiasplinepatraticaLabel     matlab.ui.control.Label
        FunctiasplinepatraticaTextArea  matlab.ui.control.TextArea
        UIAxes                          matlab.ui.control.UIAxes
        ProiectrealizatdeBarosRuxandraMariaLabel  matlab.ui.control.Label
        CalculeazaButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
       x=[];f=[];
    end
    
    methods (Access = private)
        
        function s = splinepatratic(app,x,f)
            n=length(x);
            d(1)=0;
            for i=1:n-1
                d(i+1)=-d(i)+2*((f(i+1)-f(i))/(x(i+1)-x(i)));
    
            end

            for i=1:n-1
                p=poly(x(i));
                q=conv(p,p);
                p=[0 p];
                t1=d(i);
                t2=((d(i+1)-d(i))/(2*(x(i+1)-x(i))));
                t3=[0 0 f(i)];
                s(i,1:n-1)=t3+conv(t1,p)+conv(t2,q);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculeazaButton
        function CalculeazaButtonPushed(app, event)
            app.x=str2num(app.xEditField.Value);
            app.f=str2num(app.fEditField.Value);
            s=splinepatratic(app,app.x,app.f);
            [lin,col]=size(s);
            for i=1:lin
                aux1=sprintf('s(%d) = ',i);
                for j=1:col
                    if s(i,j)<0 || j==1
                        aux2=sprintf('%s*x^%d',strtrim(rats(s(i,j))),col-j);
                    else 
                        if col-j==1
                            aux3=sprintf('+%s*x^%d',strtrim(rats(s(i,j))),col-j);
                        else
                            aux4=sprintf('+%s*x^%d',strtrim(rats(s(i,j))),col-j);
                        end
                    end
                 end
            aux{i}=sprintf('%s %s %s %s,[%d,%d]\n',aux1,aux2,aux3,aux4,app.x(i),app.x(i+1));         
            end
            app.FunctiasplinepatraticaTextArea.Value=aux;
            n=length(app.x);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.x,app.f,'-ob');
            x1=linspace(app.x(1),app.x(end),50);
            
            for ind=1:50
                for i=1:n-1
                    if x1(ind)>=app.x(i) && x1(ind)<=app.x(i+1)
                        y(ind)=polyval(s(i,1:end),x1(ind));
                    end
                end
    
            end
            plot(app.UIAxes,x1,y,':*r');
            hold(app.UIAxes,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x=';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.fEditFieldLabel = uilabel(app.UIFigure);
            app.fEditFieldLabel.Text = 'f=';
            app.fEditField = uieditfield(app.UIFigure, 'text');
            app.INTERPOLARESPLINEPATRATICALabel = uilabel(app.UIFigure);
            app.INTERPOLARESPLINEPATRATICALabel.Text = 'INTERPOLARE SPLINE PATRATICA';
            app.FunctiasplinepatraticaLabel = uilabel(app.UIFigure);
            app.FunctiasplinepatraticaLabel.Text = {'Functia '; 'spline '; 'patratica:'};
            app.FunctiasplinepatraticaTextArea = uitextarea(app.UIFigure);
            app.FunctiasplinepatraticaTextArea.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Functia s')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ProiectrealizatdeBarosRuxandraMariaLabel = uilabel(app.UIFigure);
            app.ProiectrealizatdeBarosRuxandraMariaLabel.Text = {'Proiect realizat de:'; 'Baros Ruxandra-Maria'};
            app.CalculeazaButton = uibutton(app.UIFigure, 'push');
            app.CalculeazaButton.ButtonPushedFcn = createCallbackFcn(app, @CalculeazaButtonPushed, true);
            app.CalculeazaButton.Text = 'Calculeaza';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = splinepatratic25

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end